classdef SIMReconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIMReconstructionUIFigure  matlab.ui.Figure
        FilePath                   matlab.ui.control.EditField
        ImportButton               matlab.ui.control.Button
        PixelSizenmEditFieldLabel  matlab.ui.control.Label
        PixelSize                  matlab.ui.control.NumericEditField
        TimepointsLabel            matlab.ui.control.Label
        Timepoints                 matlab.ui.control.NumericEditField
        Slices                     matlab.ui.control.NumericEditField
        zSlicesLabel               matlab.ui.control.Label
        ChannelsPanel              matlab.ui.container.Panel
        laser405                   matlab.ui.control.CheckBox
        laser488                   matlab.ui.control.CheckBox
        laser561                   matlab.ui.control.CheckBox
        laser647                   matlab.ui.control.CheckBox
        GoButton                   matlab.ui.control.Button
        NA                         matlab.ui.control.NumericEditField
        NALabel                    matlab.ui.control.Label
        SetupButtonGroup           matlab.ui.container.ButtonGroup
        AtheiSIMButton             matlab.ui.control.RadioButton
        SLMSIMButton               matlab.ui.control.RadioButton
        UnknownButton              matlab.ui.control.RadioButton
        TabGroup                   matlab.ui.container.TabGroup
        PreProcessingTab           matlab.ui.container.Tab
        MaskFactorLabel            matlab.ui.control.Label
        MaskFactor                 matlab.ui.control.NumericEditField
        bleachCorrect              matlab.ui.control.CheckBox
        backgroundSubtraction      matlab.ui.control.CheckBox
        backgroundWidth            matlab.ui.control.NumericEditField
        removalLabel               matlab.ui.control.Label
        upsample                   matlab.ui.control.CheckBox
        NoiseFilteringTab          matlab.ui.container.Tab
        WienerfactorLabel          matlab.ui.control.Label
        Wiener                     matlab.ui.control.NumericEditField
        FilterIn                   matlab.ui.control.CheckBox
        FilterOut                  matlab.ui.control.CheckBox
        jRLiterations              matlab.ui.control.NumericEditField
        jRLiterationsLabel         matlab.ui.control.Label
        jRL                        matlab.ui.control.CheckBox
        AdvancedTab                matlab.ui.container.Tab
        peakWidth                  matlab.ui.control.NumericEditField
        WidthLabel                 matlab.ui.control.Label
        attenuateOTF               matlab.ui.control.CheckBox
        attenuateStripes           matlab.ui.control.CheckBox
        WidthLabel_2               matlab.ui.control.Label
        strpWidth                  matlab.ui.control.NumericEditField
        FTProj                     matlab.ui.control.Button
        DebugLabel                 matlab.ui.control.Label
        PIV                        matlab.ui.control.Button
        MCM                        matlab.ui.control.Button
        debug                      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [file, path] = uigetfile('*.tif');
            if isequal(file,0)
                return
            end
            file = fullfile(path,file);
            frame = 1;
            temp = double(imread(file,frame));
            [xsize,ysize]=size(temp);
            bigger = get(app.upsample,'value');
            
            for a = 1:3
                for p = 1:3
                    temp = double(imread(file,frame));
                    temp = temp-min(temp(:));
                    temp = temp./max(temp(:));
                     
                    %% Handles the upsampling 
                    if bigger == 1
                        imstack(:,:,a,p) = imresize(temp,[2*xsize, 2*ysize],'nearest');   
                    else
                        imstack(:,:,a,p) = temp;     
                    end
%                           %% Handles the photbleach correction  
                    if get(app.bleachCorrect,'value') == 1
                        imstack(:,:,a,p) = imhistmatch(imstack(:,:,a,p),imstack(:,:,1,1));
                    end
                    %% Handles the background subtraction
                    if get(app.backgroundSubtraction,'value') == 1
                        k = get(app.backgroundWidth,'value');
                        k = round(k*xsize*ysize/100);
                        temp = imstack(:,:,a,p);
                        value = max(mink(temp(:),k));
                        temp = temp-value;
                        temp(temp<0) = 0;
                        imstack(:,:,a,p) = temp;
                     end
                              
                    frame = frame+1;
                end
            end
            imstack = imstack-min(imstack(:));            
            WF = mean(mean(imstack,4),3);
            imshow(WF,[0 1],'border','tight');
               
            set(app.FilePath,'Value', file);
            set(app.GoButton,'enable','on');
            set(app.FTProj,'enable','on');
            set(app.PIV,'enable','on');


        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            %% Extract reconstruction parameters
            figure();
            NA = get(app.NA,'Value');
            tPoints = get(app.Timepoints,'Value');
            slices= get(app.Slices,'Value');
            file = get(app.FilePath,'Value');
            temp = double(imread(file,1));
            [xsize,ysize]=size(temp);
            psize = get(app.PixelSize,'value')/2;
            wiener_factor = get(app.Wiener,'value');
            mask_factor = get(app.MaskFactor,'value');
            preFilter = get(app.FilterIn,'value');
            postFilter = get(app.FilterOut,'Value');
            highNoise = get(app.jRL,'value');
            n_iter = round(get(app.jRLiterations,'value'));
            bigger = get(app.upsample,'value');
            apo = get(app.attenuateOTF,'value');
            apoWidth = get(app.peakWidth,'value');
            stripe = get(app.attenuateStripes,'value');
            stripeWidth = get(app.strpWidth,'value');
            verbose = get(app.debug,'value');
            
            %Use predefined shifts
            if get(app.SLMSIMButton,'value') == 0
                shift = 0;
            else
                shift = 1;
            end
            
            shiftValues = [];
            
            %% Begin reconstruction
            
            ref = double((imread(file,1)));
            ref = ref-min(ref(:));
            ref = ref./max(ref(:));
            c = get(app.laser405,'value') + get(app.laser488,'value') + get(app.laser561,'value') + get(app.laser647,'value');
            
            f = waitbar(0,'Reconstructing...');
            counter = 0;
            for t = 1:tPoints
                for s = 1:slices
                    frame = 1;
                    waitbar(t/(tPoints*slices));
                %% 405 reconstruction    
                if get(app.laser405,'value') == 1
                    
                    for a = 1:3
                        for p = 1:3
                            temp = double(imread(file,frame+9*counter*c));
                            temp = temp-min(temp(:));
                            temp = temp./max(temp(:));
                            %% Handles the upsampling 
                            imstack(:,:,a,p) = temp;     
                            
%                           %% Handles the photbleach correction  
                            if get(app.bleachCorrect,'value') == 1
                                imstack(:,:,a,p) = imhistmatch(imstack(:,:,a,p),ref);
                            end
                            %% Handles the background subtraction
                            
                            if get(app.backgroundSubtraction,'value') == 1
                                k = get(app.backgroundWidth,'value');
                                k = round(k*xsize*ysize/100);
                                temp = imstack(:,:,a,p);
                                value = max(mink(temp(:),k));
                                temp = temp-value;
                                temp(temp<0) = 0;
                                imstack(:,:,a,p) = temp;
                             end
                            
                            frame = frame+1;
                        end
                    end
                    
                    if get(app.SLMSIMButton,'value') == 1
                        shiftValues = [0 146; -127 -73; 127 -73];
                    end
                    
                    [wide, reconstructed, FT] = reconWicker(double(imstack), 450, psize, NA, wiener_factor, mask_factor, ...
                        preFilter,postFilter,highNoise,n_iter,apo,apoWidth,bigger,stripe,stripeWidth,shift,shiftValues, verbose);
                
                    imwrite(uint16(wide),[file(1:end-4) '-405-wide.tif'], 'writemode','append');
                    imwrite(uint16(reconstructed),[file(1:end-4) '-405-SIM.tif'], 'writemode','append');
               end
                
                 %% 488 reconstruction    
                if get(app.laser488,'value') == 1
                    
                    for a = 1:3
                        for p = 1:3
                            temp = double(imread(file,frame+9*counter*c));
                            temp = temp-min(temp(:));
                            temp = temp./max(temp(:));
                            imstack(:,:,a,p) = temp;
                            
                            if get(app.bleachCorrect,'value') == 1
                                imstack(:,:,a,p) = imhistmatch(imstack(:,:,a,p),ref);
                            end
                            %% Handles the background subtraction
                            if get(app.backgroundSubtraction,'value') == 1
                                k = get(app.backgroundWidth,'value');
                                k = round(k*xsize*ysize/100);
                                temp = imstack(:,:,a,p);
                                value = max(mink(temp(:),k));
                                temp = temp-value;
                                temp(temp<0) = 0;
                                imstack(:,:,a,p) = temp;
                             end
                             
                            frame = frame+1;
                        end
                    end
                    
                    if get(app.SLMSIMButton,'value') == 1
                        shiftValues = [0 146; -127 -73; 127 -73];
                    end
                    
                    [wide, reconstructed, FT] = reconWicker(double(imstack), 520, psize, NA, wiener_factor, mask_factor, ...
                        preFilter,postFilter,highNoise,n_iter,apo,apoWidth,bigger,stripe,stripeWidth,shift,shiftValues, verbose);
                
                     imwrite(uint16(wide),[file(1:end-4) '-488-wide.tif'], 'writemode','append');
                     imwrite(uint16(reconstructed),[file(1:end-4) '-488-SIM.tif'], 'writemode','append');
                end
                
                %% 561 reconstruction    
                if get(app.laser561,'value') == 1
                    
                    for a = 1:3
                        for p = 1:3
                            temp = double(imread(file,frame+9*counter*c));
                            temp = temp-min(temp(:));
                            temp = temp./max(temp(:));
                            imstack(:,:,a,p) = temp;     
                             
                            
                            if get(app.bleachCorrect,'value') == 1
                                imstack(:,:,a,p) = imhistmatch(imstack(:,:,a,p),ref);
                            end
                            
                            %% Handles the background subtraction
                            if get(app.backgroundSubtraction,'value') == 1
                                k = get(app.backgroundWidth,'value');
                                k = round(k*xsize*ysize/100);
                                temp = imstack(:,:,a,p);
                                value = max(mink(temp(:),k));
                                temp = temp-value;
                                temp(temp<0) = 0;
                                imstack(:,:,a,p) = temp;
                             end
                             
                            frame = frame+1;
                        end
                    end
                    
                    if get(app.SLMSIMButton,'value') == 1
                        shiftValues = [0 146; -127 -73; 127 -73];
                    end
                    
                    [wide, reconstructed, FT] = reconWicker(double(imstack), 600, psize, NA, wiener_factor, mask_factor, ...
                        preFilter,postFilter,highNoise,n_iter,apo,apoWidth,bigger,stripe,stripeWidth,shift,shiftValues, verbose);
                    imwrite(uint16(wide),[file(1:end-4) '-561-wide.tif'], 'writemode','append');
                    imwrite(uint16(reconstructed),[file(1:end-4) '-561-SIM.tif'], 'writemode','append');
                end                
                
                %% 647 reconstruction    
                if get(app.laser647,'value') == 1
                    
                    for a = 1:3
                        for p = 1:3
                            temp = double(imread(file,frame+9*counter*c));
                            temp = temp-min(temp(:));
                            temp = temp./max(temp(:));
                            imstack(:,:,a,p) = temp;     
                                                        
                            if get(app.bleachCorrect,'value') == 1
                                imstack(:,:,a,p) = imhistmatch(imstack(:,:,a,p),ref);
                            end
                            
                            %% Handles the background subtraction
                            if get(app.backgroundSubtraction,'value') == 1
                                k = get(app.backgroundWidth,'value');
                                k = round(k*xsize*ysize/100);
                                temp = imstack(:,:,a,p);
                                value = max(mink(temp(:),k));
                                temp = temp-value;
                                temp(temp<0) = 0;
                                imstack(:,:,a,p) = temp;
                             end
 
                            frame = frame+1;
                        end
                    end
                    
                    if get(app.SLMSIMButton,'value') == 1
                        shiftValues = [0 117; -101 -58; 101 -59];
                    end
                    
                    [wide, reconstructed, FT] = reconWicker(double(imstack), 700, psize, NA, wiener_factor, mask_factor, ...
                        preFilter,postFilter,highNoise,n_iter,apo,apoWidth,bigger,stripe,stripeWidth,shift,shiftValues, verbose);
                    imwrite(uint16(wide),[file(1:end-4) '-647-wide.tif'], 'writemode','append');
                    imwrite(uint16(reconstructed),[file(1:end-4) '-647-SIM.tif'], 'writemode','append');
                end
                delete(f);
                counter = counter+1;
                
                sbar = round(2000/(psize/2));
                start = round(1.5*sbar);
                finish = round(0.5*sbar);


                reconstructed(end-50:end-35,end-start:end-finish) = 65535;
                wide(end-50:end-35,end-start:end-finish) = 65535;
                
                subplot(1,2,1);
                imshow(reconstructed,[0 65535],'border','tight')
                subplot(1,2,2);
                imshow(wide,[0 65535],'border','tight')
                if verbose ==1
                figure(); imshow(FT,[0 65535],'border','tight')   ;
                end
                
                drawnow
                end
            end
            
        end

        % Value changed function: jRL
        function jRLValueChanged(app, event)
            value = app.jRL.Value;
            if value == 1
                set(app.jRLiterations,'enable', 'on');
                set(app.Wiener,'enable','off');
            else
                set(app.jRLiterations,'enable', 'off');
                set(app.Wiener,'enable','on');
            end
            
        end

        % Value changed function: attenuateOTF
        function attenuateOTFValueChanged(app, event)
            value = app.attenuateOTF.Value;
            set(app.peakWidth,'enable',value);
%             set(app.attenuateStripes,'value', 0);
%             set(app.attenuateStripes,'enable',~value);
        end

        % Value changed function: backgroundSubtraction
        function backgroundSubtractionValueChanged(app, event)
            value = app.backgroundSubtraction.Value;
            set(app.backgroundWidth,'enable',value);
        end

        % Value changed function: attenuateStripes
        function attenuateStripesValueChanged(app, event)
            value = app.attenuateStripes.Value;
            set(app.strpWidth,'enable',value);
        end

        % Button pushed function: FTProj
        function FTProjButtonPushed(app, event)
            
            file = get(app.FilePath,'Value');
            frame = 1;
            PSF_edge = fspecial('gaussian',6,15);
            for a = 1:9
                temp = double(imread(file,a));
                temp = temp-min(temp(:));
                temp = temp./max(temp(:));
                imstack(:,:,a) = temp;

                [xsize, ysize] = size(temp);
                
                %% Handles the photbleach correction  
                if get(app.bleachCorrect,'value') == 1
                    imstack(:,:,a) = imhistmatch(imstack(:,:,a),imstack(:,:,1));
                end
                %% Handles the background subtraction
                
                if get(app.backgroundSubtraction,'value') == 1
                    k = get(app.backgroundWidth,'value');
                    k = round(k*xsize*ysize/100);
                    temp = imstack(:,:,a);
                    value = max(mink(temp(:),k));
                    temp = temp-value;
                    temp(temp<0) = 0;
                    imstack(:,:,a) = temp;
                end 
                imstack(:,:,a) = edgetaper(imstack(:,:,a),PSF_edge);
            end
            
            
            FT = fftshift(fft2(imstack));
            FT = log(abs(FT+1));
            FT = max(FT,[],3);
            FT = FT./max(FT(:));
            [X, Y] = meshgrid(round(-xsize/2):1:round(xsize/2)-1,round(-xsize/2):1:round(xsize/2)-1);
            [~, R] = cart2pol(X,Y);
            FT(R<round(xsize/8)) = min(FT(:));
            figure(); imagesc(FT); colormap gray; axis square; axis off; hold on;
           
            
        end

        % Button pushed function: PIV
        function PIVButtonPushed(app, event)
            file = get(app.FilePath,'Value');
            frame = 1;
            PSF_edge = fspecial('gaussian',6,15);
            for a = 1:9
                temp = double(imread(file,a));
                temp = temp-min(temp(:));
                temp = temp./max(temp(:));
                imstack(:,:,a) = temp;

                [xsize, ysize] = size(temp);
                
                %% Handles the photbleach correction  
                if get(app.bleachCorrect,'value') == 1
                    imstack(:,:,a) = imhistmatch(imstack(:,:,a),imstack(:,:,1));
                end
                %% Handles the background subtraction
                
                if get(app.backgroundSubtraction,'value') == 1
                    k = get(app.backgroundWidth,'value');
                    k = round(k*xsize*ysize/100);
                    temp = imstack(:,:,a);
                    value = max(mink(temp(:),k));
                    temp = temp-value;
                    temp(temp<0) = 0;
                    imstack(:,:,a) = temp;
                end 
            end
            wf1(:,:,1) = mean(imstack(:,:,1:3),3);
            wf1(:,:,2) = mean(imstack(:,:,4:6),3);
            wf1(:,:,3) = mean(imstack(:,:,7:9),3);
            
            figure(); imshow(wf1)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SIMReconstructionUIFigure = uifigure('Visible', 'off');
            app.SIMReconstructionUIFigure.Name = 'SIM Reconstruction';
            app.FilePath = uieditfield(app.SIMReconstructionUIFigure, 'text');
            app.FilePath.Editable = 'off';
            app.ImportButton = uibutton(app.SIMReconstructionUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PixelSizenmEditFieldLabel = uilabel(app.SIMReconstructionUIFigure);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size / nm';
            app.PixelSize = uieditfield(app.SIMReconstructionUIFigure, 'numeric');
            app.PixelSize.Limits = [0 500];
            app.PixelSize.Tooltip = {'For the water objectives:'; 'AtheSIM = 84'; 'SLM SIM = 107.5'; ''; 'For TIRF objective:'; 'SLM SIM = 63'};
            app.PixelSize.Value = 84.6;
            app.TimepointsLabel = uilabel(app.SIMReconstructionUIFigure);
            app.TimepointsLabel.Text = 'Timepoints';
            app.Timepoints = uieditfield(app.SIMReconstructionUIFigure, 'numeric');
            app.Timepoints.Limits = [1 Inf];
            app.Timepoints.Tooltip = {'Number of timepoint (min = 1)'};
            app.Timepoints.Value = 1;
            app.Slices = uieditfield(app.SIMReconstructionUIFigure, 'numeric');
            app.Slices.Limits = [1 Inf];
            app.Slices.Tooltip = {'Number of Z slices (min = 1)'};
            app.Slices.Value = 1;
            app.zSlicesLabel = uilabel(app.SIMReconstructionUIFigure);
            app.zSlicesLabel.Text = 'z Slices';
            app.ChannelsPanel = uipanel(app.SIMReconstructionUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.laser405 = uicheckbox(app.ChannelsPanel);
            app.laser405.Text = '405';
            app.laser488 = uicheckbox(app.ChannelsPanel);
            app.laser488.Text = '488';
            app.laser561 = uicheckbox(app.ChannelsPanel);
            app.laser561.Text = '561';
            app.laser561.Value = true;
            app.laser647 = uicheckbox(app.ChannelsPanel);
            app.laser647.Text = '647';
            app.GoButton = uibutton(app.SIMReconstructionUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.NA = uieditfield(app.SIMReconstructionUIFigure, 'numeric');
            app.NA.Limits = [0 500];
            app.NA.Tooltip = {'For the water objectives:'; 'NA = 1.2'; 'For TIRF objectives:'; 'NA = 1.49'};
            app.NA.Value = 1.2;
            app.NALabel = uilabel(app.SIMReconstructionUIFigure);
            app.NALabel.Text = 'NA';
            app.SetupButtonGroup = uibuttongroup(app.SIMReconstructionUIFigure);
            app.SetupButtonGroup.Tooltip = {'Selecting specific setup may help improve reconstruction times'};
            app.SetupButtonGroup.Title = 'Setup';
            app.AtheiSIMButton = uiradiobutton(app.SetupButtonGroup);
            app.AtheiSIMButton.Enable = 'off';
            app.AtheiSIMButton.Tooltip = {'Use for Atheisim data post 12/2019'};
            app.AtheiSIMButton.Text = 'AtheiSIM';
            app.SLMSIMButton = uiradiobutton(app.SetupButtonGroup);
            app.SLMSIMButton.Enable = 'off';
            app.SLMSIMButton.Tooltip = {'Use for SLM SIM data post 12/2019'};
            app.SLMSIMButton.Text = 'SLM - SIM';
            app.UnknownButton = uiradiobutton(app.SetupButtonGroup);
            app.UnknownButton.Tooltip = {'Use for all other SIM configurations'};
            app.UnknownButton.Text = 'Unknown';
            app.UnknownButton.Value = true;
            app.TabGroup = uitabgroup(app.SIMReconstructionUIFigure);
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.MaskFactorLabel = uilabel(app.PreProcessingTab);
            app.MaskFactorLabel.Text = 'Mask Factor';
            app.MaskFactor = uieditfield(app.PreProcessingTab, 'numeric');
            app.MaskFactor.Limits = [0 1];
            app.MaskFactor.Tooltip = {'Region of FT to exclude. Use 0.4-0.7.'};
            app.MaskFactor.Value = 0.4;
            app.bleachCorrect = uicheckbox(app.PreProcessingTab);
            app.bleachCorrect.Tooltip = {'Normalises image histograms to correct for laser fluctuation and moderate photo-bleaching.'};
            app.bleachCorrect.Text = 'Correct bleaching';
            app.backgroundSubtraction = uicheckbox(app.PreProcessingTab);
            app.backgroundSubtraction.ValueChangedFcn = createCallbackFcn(app, @backgroundSubtractionValueChanged, true);
            app.backgroundSubtraction.Tooltip = {'Substracts the darkest 2% of pixels in the image.'};
            app.backgroundSubtraction.Text = 'Remove background';
            app.backgroundWidth = uieditfield(app.PreProcessingTab, 'numeric');
            app.backgroundWidth.Limits = [0 100];
            app.backgroundWidth.Enable = 'off';
            app.backgroundWidth.Tooltip = {'Pecentage of pixels in image categorised as blank background (not out-of-focus background).'};
            app.backgroundWidth.Value = 10;
            app.removalLabel = uilabel(app.PreProcessingTab);
            app.removalLabel.Text = '% removal';
            app.upsample = uicheckbox(app.PreProcessingTab);
            app.upsample.Tooltip = {'Double size of output image. Required if pixel size does not meet Niquist sampling. '};
            app.upsample.Text = 'Upsample';
            app.NoiseFilteringTab = uitab(app.TabGroup);
            app.NoiseFilteringTab.Title = 'Noise Filtering';
            app.WienerfactorLabel = uilabel(app.NoiseFilteringTab);
            app.WienerfactorLabel.Text = 'Wiener factor';
            app.Wiener = uieditfield(app.NoiseFilteringTab, 'numeric');
            app.Wiener.Limits = [0.001 5];
            app.Wiener.Tooltip = {'Filtering factor. '; '0.5-1 = low filtering'; '0.1-0.5 = average filtering'; '0.05-0.1= high filtering. Use low filtering values if unsure.'};
            app.Wiener.Value = 1;
            app.FilterIn = uicheckbox(app.NoiseFilteringTab);
            app.FilterIn.Text = 'Use pre-filtering';
            app.FilterOut = uicheckbox(app.NoiseFilteringTab);
            app.FilterOut.Text = 'Use post-filtering';
            app.jRLiterations = uieditfield(app.NoiseFilteringTab, 'numeric');
            app.jRLiterations.Limits = [1 500];
            app.jRLiterations.Enable = 'off';
            app.jRLiterations.Tooltip = {'number of RL iterations. '; 'Typical values 2<n<10.'; 'Caution: excessive iterations may artificially increase point-source resolution.'};
            app.jRLiterations.Value = 3;
            app.jRLiterationsLabel = uilabel(app.NoiseFilteringTab);
            app.jRLiterationsLabel.Text = 'jRL iterations';
            app.jRL = uicheckbox(app.NoiseFilteringTab);
            app.jRL.ValueChangedFcn = createCallbackFcn(app, @jRLValueChanged, true);
            app.jRL.Tooltip = {'Apply RL deconvolution for high nose images'};
            app.jRL.Text = 'High noise imaging';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.peakWidth = uieditfield(app.AdvancedTab, 'numeric');
            app.peakWidth.Limits = [0 50];
            app.peakWidth.Enable = 'off';
            app.peakWidth.Tooltip = {'Magnitude of suppression. Typical values 3-0.1'};
            app.peakWidth.Value = 1.2;
            app.WidthLabel = uilabel(app.AdvancedTab);
            app.WidthLabel.Text = 'Width';
            app.attenuateOTF = uicheckbox(app.AdvancedTab);
            app.attenuateOTF.ValueChangedFcn = createCallbackFcn(app, @attenuateOTFValueChanged, true);
            app.attenuateOTF.Tooltip = {'Removes zero frequency component. Can help reject background signal (useful in z-stacks). '};
            app.attenuateOTF.Text = 'OTF suppression ';
            app.attenuateStripes = uicheckbox(app.AdvancedTab);
            app.attenuateStripes.ValueChangedFcn = createCallbackFcn(app, @attenuateStripesValueChanged, true);
            app.attenuateStripes.Tooltip = {'Attenuate pattern peaks in Fourier space. May help remove striping artefacts.'};
            app.attenuateStripes.Text = 'Stripe suppression';
            app.WidthLabel_2 = uilabel(app.AdvancedTab);
            app.WidthLabel_2.Text = 'Width';
            app.strpWidth = uieditfield(app.AdvancedTab, 'numeric');
            app.strpWidth.Limits = [0 50];
            app.strpWidth.Enable = 'off';
            app.strpWidth.Tooltip = {'The width of the attenuation filter. Typical values 2-0.1.'};
            app.strpWidth.Value = 0.2;
            app.FTProj = uibutton(app.AdvancedTab, 'push');
            app.FTProj.ButtonPushedFcn = createCallbackFcn(app, @FTProjButtonPushed, true);
            app.FTProj.Enable = 'off';
            app.FTProj.Text = 'FT Proj.';
            app.DebugLabel = uilabel(app.AdvancedTab);
            app.DebugLabel.Text = 'Debug:';
            app.PIV = uibutton(app.AdvancedTab, 'push');
            app.PIV.ButtonPushedFcn = createCallbackFcn(app, @PIVButtonPushed, true);
            app.PIV.Enable = 'off';
            app.PIV.Text = 'MIV';
            app.MCM = uibutton(app.AdvancedTab, 'push');
            app.MCM.Enable = 'off';
            app.MCM.Text = 'MCN';
            app.debug = uicheckbox(app.AdvancedTab);
            app.debug.Tooltip = {'Show Fourier transform of reconstruction. Successful reconstruction will have symmetric flower shape.'};
            app.debug.Text = 'Debug';
            % Show the figure after all components are created
            app.SIMReconstructionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIMReconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIMReconstructionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIMReconstructionUIFigure)
        end
    end
end